classdef Calculadora_de_Transformadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        test1            matlab.ui.control.NumericEditField
        text2            matlab.ui.control.NumericEditField
        text3            matlab.ui.control.NumericEditField
        text4            matlab.ui.control.NumericEditField
        text5            matlab.ui.control.NumericEditField
        text6            matlab.ui.control.NumericEditField
        text7            matlab.ui.control.NumericEditField
        text25           matlab.ui.control.NumericEditField
        text9            matlab.ui.control.NumericEditField
        text10           matlab.ui.control.NumericEditField
        text11           matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        text12           matlab.ui.control.NumericEditField
        EditFieldLabel2  matlab.ui.control.Label
        text13           matlab.ui.control.NumericEditField
        EditFieldLabel3  matlab.ui.control.Label
        text14           matlab.ui.control.NumericEditField
        EditFieldLabel4  matlab.ui.control.Label
        text15           matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        text16           matlab.ui.control.NumericEditField
        button2          matlab.ui.control.Button
        Label1           matlab.ui.control.Label
        Label2           matlab.ui.control.Label
        Label3           matlab.ui.control.Label
        Label4           matlab.ui.control.Label
        Label5           matlab.ui.control.Label
        Label6           matlab.ui.control.Label
        text17           matlab.ui.control.NumericEditField
        Button3          matlab.ui.control.Button
        Label7           matlab.ui.control.Label
        Label8           matlab.ui.control.Label
        text18           matlab.ui.control.NumericEditField
        Button4          matlab.ui.control.Button
        Label9           matlab.ui.control.Label
        text19           matlab.ui.control.NumericEditField
        Button5          matlab.ui.control.Button
        Label10          matlab.ui.control.Label
        Label11          matlab.ui.control.Label
        BEditFieldLabel  matlab.ui.control.Label
        text20           matlab.ui.control.NumericEditField
        Button6          matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)


    end
    

    methods (Access = private)

        % Button pushed function: button2
        function button2Pushed(app, event)
            app.text12.Value = ((app.text25.Value+(app.text9.Value/2)+(app.text7.Value/2))/100)/ ((app.text10.Value) * ( (4*pi)*10^-7 ) * ((app.text3.Value * app.text6.Value)/10000));
            app.text13.Value = ((app.text4.Value+(app.text3.Value/2)+(app.text5.Value/2))/100)/ ((app.text10.Value) * ( (4*pi)*10^-7 ) * ((app.text9.Value * app.text6.Value)/10000));
            app.text14.Value = ((app.text25.Value+(app.text9.Value/2)+(app.text7.Value/2))/100)/ ((app.text10.Value) * ( (4*pi)*10^-7 ) * ((app.text5.Value * app.text6.Value)/10000));
            
            app.text15.Value = ((app.text4.Value+(app.text3.Value/2)+(app.text5.Value/2))/100)/ ((app.text10.Value) * ( (4*pi)*10^-7 ) * ((app.text7.Value * app.text6.Value)/10000));
            
            app.text16.Value = app.text12.Value + app.text13.Value + app.text14.Value + app.text15.Value;
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            app.text17.Value = ((app.text11.Value)*(app.text2.Value))/(app.text16.Value);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            app.text18.Value = (app.text10.Value)*(( (4*pi)*10^-7 ));
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            app.text19.Value = ((app.text11.Value)*(app.text2.Value))/((app.text3.Value+(app.text4.Value * 2)+app.text5.Value+app.text7.Value+(app.text25.Value * 2)+ app.text9.Value)/100);
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            app.text20.Value = (app.text18.Value * app.text19.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'WhatsApp Image 2018-10-04 at 2.52.59 PM.jpeg';
            app.Button.Text = '';
            app.test1 = uieditfield(app.UIFigure, 'numeric');
            app.test1.Limits = [0 Inf];
            app.text2 = uieditfield(app.UIFigure, 'numeric');
            app.text2.Limits = [0 Inf];
            app.text3 = uieditfield(app.UIFigure, 'numeric');
            app.text3.Limits = [0.001 Inf];
            app.text3.Value = 0.001;
            app.text4 = uieditfield(app.UIFigure, 'numeric');
            app.text4.Limits = [0.001 Inf];
            app.text4.Value = 0.001;
            app.text5 = uieditfield(app.UIFigure, 'numeric');
            app.text5.Limits = [0.001 Inf];
            app.text5.Value = 0.001;
            app.text6 = uieditfield(app.UIFigure, 'numeric');
            app.text6.Limits = [0.001 Inf];
            app.text6.Value = 0.001;
            app.text7 = uieditfield(app.UIFigure, 'numeric');
            app.text7.Limits = [0.001 Inf];
            app.text7.Value = 0.001;
            app.text25 = uieditfield(app.UIFigure, 'numeric');
            app.text25.Limits = [0.001 Inf];
            app.text25.Value = 0.001;
            app.text9 = uieditfield(app.UIFigure, 'numeric');
            app.text9.Limits = [0.001 Inf];
            app.text9.Value = 0.001;
            app.text10 = uieditfield(app.UIFigure, 'numeric');
            app.text10.Limits = [0 Inf];
            app.text11 = uieditfield(app.UIFigure, 'numeric');
            app.text11.Limits = [1 Inf];
            app.text11.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'R1';
            app.text12 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel2 = uilabel(app.UIFigure);
            app.EditFieldLabel2.Text = 'R2';
            app.text13 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel3 = uilabel(app.UIFigure);
            app.EditFieldLabel3.Text = 'R3';
            app.text14 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel4 = uilabel(app.UIFigure);
            app.EditFieldLabel4.Text = 'R4';
            app.text15 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'RT';
            app.text16 = uieditfield(app.UIFigure, 'numeric');
            app.button2 = uibutton(app.UIFigure, 'push');
            app.button2.ButtonPushedFcn = createCallbackFcn(app, @button2Pushed, true);
            app.button2.Text = 'Calcular Resistencias';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'AV/Wb';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'AV/Wb';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'AV/Wb';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'AV/Wb';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'AV/Wb';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = {'Flujo'; 'Electromagnetico'};
            app.text17 = uieditfield(app.UIFigure, 'numeric');
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Calcular Flujo';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Wb';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'U';
            app.text18 = uieditfield(app.UIFigure, 'numeric');
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Calcular U';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'H';
            app.text19 = uieditfield(app.UIFigure, 'numeric');
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = 'Calcular H';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Lens';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'H/m';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.text20 = uieditfield(app.UIFigure, 'numeric');
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = 'Calcular B';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calculadora_de_Transformadores

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end